classdef WireTwistViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        RemoveOuterLayerButton  matlab.ui.control.Button
        LengthinEditField       matlab.ui.control.NumericEditField
        LengthinEditFieldLabel  matlab.ui.control.Label
        AWGEditField            matlab.ui.control.NumericEditField
        AWGEditFieldLabel       matlab.ui.control.Label
        LayerEditField          matlab.ui.control.NumericEditField
        LayerEditFieldLabel     matlab.ui.control.Label
        AddButton               matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        LayFactor = 10;
        LayerMinAWG = [];
        % Distance from surface of layer to center
        LayerDistFromCent = [];
        LayerWiresPerLayer = [1,6,12,18,24,30,36,42]
        totalWiresPerLayer = [1,7,19,37,61,91,127,169]
        layer = 0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if app.layer ~= 8
                % Grab data                
                awg = app.AWGEditField.Value;
                length = app.LengthinEditField.Value;
                layerStr = sprintf('%d', app.layer);
                awgStr   = sprintf('%d', awg);
                % Calculated twisted length
                if app.layer == 0
                    tLength = length;
                else
                    tLength = length * sqrt(1 + (pi/app.LayFactor)^2);
                end
                % LayerMinAWG list updates:
                % Determine the index (Layer 0 is Index 1, Layer 1 is Index 2, etc.)
                idx = app.layer + 1;
                % Get # of wires
                numOfWires = app.LayerWiresPerLayer(idx);
                totalOfWires = app.totalWiresPerLayer(idx);
                qtyStr   = sprintf('%d', numOfWires);
                tQtyStr   = sprintf('%d', totalOfWires);
                    % Check if this is a new layer we haven't seen before
                if idx > numel(app.LayerMinAWG)
                    % Expand the list. We initialize with a high number (like 99) 
                    % so that any real AWG added will be "smaller" than the default.
                    app.LayerMinAWG(idx) = 99;
                    app.LayerDistFromCent(idx) = 0;
                end
                % Update only if the new wire is a smaller gauge
                if awg < app.LayerMinAWG(idx)
                    app.LayerMinAWG(idx) = awg;
                    % Get diameter and radius for the CURRENT wire being updated
                    diam = 0.005 * 92^((36 - awg) / 39);
                    rad = diam / 2;
                    if idx == 1
                        % Layer 0: Center is the origin
                        app.LayerDistFromCent(idx) = 0;
                    else
                        % Get the radius of the "biggest" wire from the layer immediately below
                        prevAWG = app.LayerMinAWG(idx-1);
                        prevRad = (0.005 * 92^((36 - prevAWG) / 39)) / 2;    
                        % Recursively calculate distance from center
                        app.LayerDistFromCent(idx) = app.LayerDistFromCent(idx-1) + prevRad + rad;
                    end
                end
                % Get layLength
                layLength = app.LayFactor * (2 * app.LayerDistFromCent(idx));
                % Make data into a new row
                newRow = {layerStr,awgStr,length, tLength, layLength, qtyStr, tQtyStr};
                % Append data to table
                existingData = app.UITable.Data;
                updatedData = [existingData; newRow];
                app.UITable.Data = updatedData;
                % Update layer
                app.layer = app.layer + 1;
                app.LayerEditField.Value = app.layer;
            end
        end

        % Value changed function: LayerEditField
        function LayerEditFieldValueChanged(app, event)
            value = app.LayerEditField.Value;
            
        end

        % Button pushed function: RemoveOuterLayerButton
        function RemoveOuterLayerButtonPushed(app, event)
            % Get the current data from the table
            currentData = app.UITable.Data;
    
            % Check if the table is already empty to prevent errors
            if ~isempty(currentData)
                % Remove the last row using end-index slicing
                currentData(end, :) = [];
        
                % Reassign the truncated data back to the table
                app.layer = app.layer - 1;
                app.LayerEditField.Value = app.layer;
                app.UITable.Data = currentData;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wire Cross Section')
            xlabel(app.UIAxes, 'Cross Section Width (inch)')
            ylabel(app.UIAxes, 'Cross Section Height (inch)')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Layer'; 'AWG'; 'Length(in)'; 'Twisted Length(in)'; 'Lay Length(in)'; 'Wires per Layer'; 'Total Wires'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true true false false false];
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.LayerEditFieldLabel = uilabel(app.UIFigure);
            app.LayerEditFieldLabel.Text = 'Layer';
            app.LayerEditField = uieditfield(app.UIFigure, 'numeric');
            app.LayerEditField.ValueChangedFcn = createCallbackFcn(app, @LayerEditFieldValueChanged, true);
            app.LayerEditField.Editable = 'off';
            app.AWGEditFieldLabel = uilabel(app.UIFigure);
            app.AWGEditFieldLabel.Text = 'AWG';
            app.AWGEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthinEditFieldLabel = uilabel(app.UIFigure);
            app.LengthinEditFieldLabel.Text = 'Length(in)';
            app.LengthinEditField = uieditfield(app.UIFigure, 'numeric');
            app.RemoveOuterLayerButton = uibutton(app.UIFigure, 'push');
            app.RemoveOuterLayerButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveOuterLayerButtonPushed, true);
            app.RemoveOuterLayerButton.Text = 'Remove Outer Layer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WireTwistViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end